classdef maze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LaberintoLabel         matlab.ui.control.Label
        DImensionesLabel       matlab.ui.control.Label
        AnchoEditField_2Label  matlab.ui.control.Label
        AnchoEditField         matlab.ui.control.NumericEditField
        LargoEditFieldLabel    matlab.ui.control.Label
        LargoEditField         matlab.ui.control.NumericEditField
        CrearButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        InicioLabel            matlab.ui.control.Label
        xEditFieldLabel        matlab.ui.control.Label
        InicioXEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel        matlab.ui.control.Label
        InicioYEditField       matlab.ui.control.NumericEditField
        MetaLabel              matlab.ui.control.Label
        xEditField_2Label      matlab.ui.control.Label
        MetaXEditField         matlab.ui.control.NumericEditField
        yEditField_2Label      matlab.ui.control.Label
        MetaYEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Maze
        MazeCheckboxes
        MazeDims
        CHECK_BOX_DIMS
        MAZE_EDITOR_POS
        MazeEditor
        AplicarCambiosButton
        ExploredCoords
        ParentMap
    end
    
    methods (Access = private)
        
        function AplicarCambiosButtonPushed(app, event)
            %TODO
            disp('yay');
            app.MazeEditor.Visible = 'off';
            app.Maze = zeros(app.MazeDims(1), app.MazeDims(2));
            for i = 1:app.MazeDims(1)
                for j = 1:app.MazeDims(2)
                    check_box = app.MazeCheckboxes(i,j);
                    app.Maze(i,j) = check_box.Value;
                end
            end
            sol = app.bfs;
            plot(app.UIAxes, sol(:,1), sol(:,2), '--r');
            hold(app.UIAxes, 'on');
            for x = 1:app.MazeDims(1)
                for y = 1:app.MazeDims(2)
                    if app.Maze(x, y) == 0
                        plot(app.UIAxes, x, y, 'xg');
                    end
                    if app.Maze(x, y) == 1
                        plot(app.UIAxes, x, y, 'sb', 'MarkerSize', 15);
                    end
                end
            end
        end
        
        function explored = is_explored(app, coord)
            explored = false;
            for i = 1:size(app.ExploredCoords, 1)
                if coord == app.ExploredCoords(i,:)
                    explored = true;
                    break;
                end
            end
        end
        
        function children = get_children(app, coord)
            x = coord(1);
            y = coord(2);
            children = [x y+1; x+1 y; x y-1; x-1 y];
            filtered_children = [];
            for i = 1:length(children)
                child = children(i,:);
                if ~(child(1) > 0 && child(1) <= app.MazeDims(1))
                    continue;
                end
                if ~(child(2) > 0 && child(2) <= app.MazeDims(2))
                    continue;
                end
                if app.is_explored(child)
                    continue;
                end
                if app.Maze(child(1),child(2)) == 1
                    continue;
                end
                filtered_children = [filtered_children; child];
                app.ParentMap(child(1)*2:child(1)*2+1, child(2)) = coord;
            end
            children = filtered_children;
        end
        
        function dist = manh_dist(~, coord1, coord2)
            dist = coord2 - coord1;
            dist = abs(dist);
            dist = dist(1) + dist(2);
        end
        
        function path = reconstruct_path(app, goal)
            path = goal;
            node = goal;
            while true
                node = app.ParentMap(node(1)*2:node(1)*2+1, node(2));
                if node == [0 0]
                    break
                end
                path = [node'; path];
            end
        end
        
        function path = bfs(app)
            start = [app.InicioXEditField.Value app.InicioYEditField.Value];
            goal = [app.MetaXEditField.Value app.MetaYEditField.Value];
            app.ParentMap = zeros(app.MazeDims(1) * 2, app.MazeDims(2));
            app.ExploredCoords = [];
            goal_found = false;
            queue = start;
            while ~goal_found
                node = queue(1,:);
                if node == goal
                    path = app.reconstruct_path(goal);
                    break;
                end
                app.ExploredCoords = [app.ExploredCoords; node];
                queue = [queue; app.get_children(node)];
                if size(queue, 1) == 0
                    path = [];
                    disp('solution not found');
                    break;
                end
                queue = queue(2:size(queue,1),:);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CHECK_BOX_DIMS = [20 20];
        end

        % Button pushed function: CrearButton
        function CrearButtonPushed(app, event)
            app.MazeDims = [app.LargoEditField.Value app.AnchoEditField.Value];
            app.MazeEditor = uifigure('Visible', 'off');
            width = app.CHECK_BOX_DIMS(1);
            height = app.CHECK_BOX_DIMS(2);
            app.MazeCheckboxes = uicheckbox(app.UIFigure, 'Visible', 'off');
            for i = 1:app.MazeDims(1)
                for j = 1:app.MazeDims(2)
                    check_box = uicheckbox(app.MazeEditor);
                    check_box.Text = '';
                    x = i * width;
                    y = j * height;
                    check_box.Position = [x y width height];
                    app.MazeCheckboxes(i,j) = check_box;
                end
            end
            button = uibutton(app.MazeEditor);
            button.Text = 'Resolver';
            button.Position(1:2) = [x + width 0];
            app.AplicarCambiosButton = button;
            app.AplicarCambiosButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarCambiosButtonPushed, true);
            app.MazeEditor.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LaberintoLabel = uilabel(app.UIFigure);
            app.LaberintoLabel.Text = 'Laberinto';
            app.DImensionesLabel = uilabel(app.UIFigure);
            app.DImensionesLabel.Text = 'Dimenciones de laberinto';
            app.AnchoEditField_2Label = uilabel(app.UIFigure);
            app.AnchoEditField_2Label.Text = 'Ancho';
            app.AnchoEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnchoEditField.Value = 7;
            app.LargoEditFieldLabel = uilabel(app.UIFigure);
            app.LargoEditFieldLabel.Text = 'Largo';
            app.LargoEditField = uieditfield(app.UIFigure, 'numeric');
            app.LargoEditField.Value = 7;
            app.CrearButton = uibutton(app.UIFigure, 'push');
            app.CrearButton.ButtonPushedFcn = createCallbackFcn(app, @CrearButtonPushed, true);
            app.CrearButton.Text = 'Crear laberinto y abrir editor!';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InicioLabel = uilabel(app.UIFigure);
            app.InicioLabel.Text = 'Inicio';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.InicioXEditField = uieditfield(app.UIFigure, 'numeric');
            app.InicioXEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.InicioYEditField = uieditfield(app.UIFigure, 'numeric');
            app.InicioYEditField.Value = 1;
            app.MetaLabel = uilabel(app.UIFigure);
            app.MetaLabel.Text = 'Meta';
            app.xEditField_2Label = uilabel(app.UIFigure);
            app.xEditField_2Label.Text = 'x';
            app.MetaXEditField = uieditfield(app.UIFigure, 'numeric');
            app.MetaXEditField.Value = 7;
            app.yEditField_2Label = uilabel(app.UIFigure);
            app.yEditField_2Label.Text = 'y';
            app.MetaYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MetaYEditField.Value = 7;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end